classdef AppV1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        EditMenu                   matlab.ui.container.Menu
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        Image1MetricsListBoxLabel  matlab.ui.control.Label
        Image1MetricsListBox       matlab.ui.control.ListBox
        CenterPanel                matlab.ui.container.Panel
        DimensionViewLabel         matlab.ui.control.Label
        PreviousSliceButton        matlab.ui.control.Button
        NextSliceButton            matlab.ui.control.Button
        ZoomSliderLabel            matlab.ui.control.Label
        ZoomSlider                 matlab.ui.control.Slider
        ScaleLabel                 matlab.ui.control.Label
        SliceofLabel               matlab.ui.control.Label
        PlaneOrientationLabel      matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
        ImportImageButton          matlab.ui.control.Button
        DualImageViewButton        matlab.ui.control.Button
        Image1Label                matlab.ui.control.Label
        PatientLabel               matlab.ui.control.Label
        DateLabel                  matlab.ui.control.Label
        MDLabel                    matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, bob, bobbie)
            
        end

        % Button pushed function: NextSliceButton
        function NextSliceButtonPushed(app, event)
            %V = niftiread(Mask.nii.gz);
            % imshow('i = Y(:,:,400)', 'Parent', app.UIAxes);
        end

        % Button pushed function: ImportImageButton
        function ImportImageButtonPushed(app, event)
        display('Load in the brain')
            [file_brain,path_brain] = uigetfile('*.nii.gz'); % saks the user to get the file
            if isequal(file_brain,0)
               disp('User selected Cancel');
            else
               disp(['User selected ', fullfile(path_brain,file_brain)]);
               imbrain = niftiread(file_brain); %creates the brain matrix
               assignin('base','imbrain',imbrain); %assigns the variable imbrain (the dowloaded image) as 'imbrain' in the workspace
            end
            
            display('Load in the mask')
            [file_mask,path_mask] = uigetfile('*.nii.gz'); % saks the user to get the file
            if isequal(file_mask,0)
               disp('User selected Cancel');
            else
               disp(['User selected ', fullfile(path_mask,file_mask)]);
               immask = niftiread(file_mask); %creates the mask matrix
               assignin('base','immask',immask); %assigns the variable immask (the dowloaded image) as 'imbrain' in the workspace
            end
            
            
            run('main.m');
            Show = uiaxes(app.UIFigure,"Position", [185 185 319 239]);%coordinates: last 2 are size, 1st is horizontal pos, 2nd is vertical pos
            imshow(bob,[],'parent',Show);
         
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {168, '1x', 145};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Image1MetricsListBoxLabel = uilabel(app.LeftPanel);
            app.Image1MetricsListBoxLabel.Text = 'Image 1 Metrics';
            app.Image1MetricsListBox = uilistbox(app.LeftPanel);
            app.Image1MetricsListBox.Items = {'Perpendicular Height:', 'Max. Diameter:', 'Size Ratio:'};
            app.Image1MetricsListBox.Value = 'Perpendicular Height:';
            app.CenterPanel = uipanel(app.GridLayout);
            app.DimensionViewLabel = uilabel(app.CenterPanel);
            app.DimensionViewLabel.Text = 'Dimension View:';
            app.PreviousSliceButton = uibutton(app.CenterPanel, 'push');
            app.PreviousSliceButton.Text = 'Previous Slice';
            app.NextSliceButton = uibutton(app.CenterPanel, 'push');
            app.NextSliceButton.ButtonPushedFcn = createCallbackFcn(app, @NextSliceButtonPushed, true);
            app.NextSliceButton.Text = 'Next Slice';
            app.ZoomSliderLabel = uilabel(app.CenterPanel);
            app.ZoomSliderLabel.Text = 'Zoom';
            app.ZoomSlider = uislider(app.CenterPanel);
            app.ScaleLabel = uilabel(app.CenterPanel);
            app.ScaleLabel.Text = 'Scale:';
            app.SliceofLabel = uilabel(app.CenterPanel);
            app.SliceofLabel.Text = 'Slice # of #';
            app.PlaneOrientationLabel = uilabel(app.CenterPanel);
            app.PlaneOrientationLabel.Text = 'Plane Orientation: ';
            app.RightPanel = uipanel(app.GridLayout);
            app.ImportImageButton = uibutton(app.RightPanel, 'push');
            app.ImportImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImportImageButtonPushed, true);
            app.ImportImageButton.Text = 'Import Image';
            app.DualImageViewButton = uibutton(app.RightPanel, 'push');
            app.DualImageViewButton.Text = 'Dual Image View';
            app.Image1Label = uilabel(app.RightPanel);
            app.Image1Label.Text = 'Image 1';
            app.PatientLabel = uilabel(app.RightPanel);
            app.PatientLabel.Text = 'Patient:';
            app.DateLabel = uilabel(app.RightPanel);
            app.DateLabel.Text = 'Date:';
            app.MDLabel = uilabel(app.RightPanel);
            app.MDLabel.Text = 'MD:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppV1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end